classdef MoeiniRomanic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        rField                      matlab.ui.control.NumericEditField
        rSlider                     matlab.ui.control.Slider
        DistancefromdowndraftcenterkmLabel  matlab.ui.control.Label
        ExportDataTableButton       matlab.ui.control.Button
        ExportPlotButton            matlab.ui.control.Button
        ModelParametersPanel        matlab.ui.container.Panel
        InitialvelField             matlab.ui.control.NumericEditField
        InitialvelSlider            matlab.ui.control.Slider
        VelocityofdowndraftatcloudbasemsLabel  matlab.ui.control.Label
        DiameterField               matlab.ui.control.NumericEditField
        DiameterSlider              matlab.ui.control.Slider
        DiameterofdowndraftkmLabel  matlab.ui.control.Label
        HField                      matlab.ui.control.NumericEditField
        ABLvelField                 matlab.ui.control.NumericEditField
        ABLvelSlider                matlab.ui.control.Slider
        VelocityofABLwindsmsLabel   matlab.ui.control.Label
        HSlider                     matlab.ui.control.Slider
        CloudbaseheightkmLabel      matlab.ui.control.Label
        MoeiniRomanic2022Label      matlab.ui.control.Label
        HorizontalAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)
        
        function u = compute_u(app, u_db, u_abl, u_nl)
            u = u_db + u_abl - u_nl;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller7)
            % Store selection menu app object
            app.CallingApp = caller7;
            
            % Define parameters for profile
            z = 0:0.1:1000;
            u_abl = app.ABLvelField.Value;
            u_j = app.InitialvelField.Value;
            D = app.DiameterField.Value * 1000;
            H = app.HField.Value * 1000;
            r = app.rField.Value * 1000;
            c_w = 1;
            lambda_j = 10000;
            z_m = 0.0196*H*(r/H)^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*(z./(H*(r/H)^0.305)))/((r/H)^1.1);
            u_nl = (6.251*u_db*(r/H)^0.2*(-0.1129 + 2.6))/(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)

            % Plot profile
            plot(app.HorizontalAxes, compute_u(app, u_db, u_abl, u_nl), z, 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis
            xlim(app.HorizontalAxes, [0, inf])
        end

        % Value changed function: ABLvelField, DiameterField, HField, 
        % InitialvelField, rField
        function FieldValueChanged(app, event)
            % Define parameters for profile
            z = 0:0.1:1000;
            u_abl = app.ABLvelField.Value;
            u_j = app.InitialvelField.Value;
            D = app.DiameterField.Value * 1000;
            H = app.HField.Value * 1000;
            r = app.rField.Value * 1000;
            c_w = 1;
            lambda_j = 10000;
            z_m = 0.0196*H*(r/H)^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*(z./(H*(r/H)^0.305)))/((r/H)^1.1);
            u_nl = (6.251*u_db*(r/H)^0.2*(-0.1129 + 2.6))/(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)

            % Plot profile
            plot(app.HorizontalAxes, compute_u(app, u_db, u_abl, u_nl), z, 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis
            xlim(app.HorizontalAxes, [0, inf])

            % Update sliders to match field values
            if app.ABLvelField.Value ~= app.ABLvelSlider.Value
                app.ABLvelSlider.Value = app.ABLvelField.Value;
            end
            if app.InitialvelField.Value ~= app.InitialvelSlider.Value
                app.InitialvelSlider.Value = app.InitialvelField.Value;
            end
            if app.DiameterField.Value ~= app.DiameterSlider.Value
                app.DiameterSlider.Value = app.DiameterField.Value;
            end
            if app.HField.Value ~= app.HSlider.Value
                app.HSlider.Value = app.HField.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            end
        end

        % Value changed function: ABLvelSlider, DiameterSlider, HSlider, 
        % InitialvelSlider, rSlider
        function SliderValueChanged(app, event)
            % Define parameters for profile
            z = 0:0.1:1000;
            u_abl = app.ABLvelSlider.Value;
            u_j = app.InitialvelSlider.Value;
            D = app.DiameterSlider.Value * 1000;
            H = app.HSlider.Value * 1000;
            r = app.rSlider.Value * 1000;
            c_w = 1;
            lambda_j = 10000;
            z_m = 0.0196*H*(r/H)^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*(z./(H*(r/H)^0.305)))/((r/H)^1.1);
            u_nl = (6.251*u_db*(r/H)^0.2*(-0.1129 + 2.6))/(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)

            % Plot profile
            plot(app.HorizontalAxes, compute_u(app, u_db, u_abl, u_nl), z, 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis
            xlim(app.HorizontalAxes, [0, inf])
            
            % Update field values to match sliders
            if app.ABLvelSlider.Value ~= app.ABLvelField.Value
                app.ABLvelField.Value = app.ABLvelSlider.Value;
            end
            if app.InitialvelSlider.Value ~= app.InitialvelField.Value
                app.InitialvelField.Value = app.InitialvelSlider.Value;
            end
            if app.DiameterSlider.Value ~= app.DiameterField.Value
                app.DiameterField.Value = app.DiameterSlider.Value;
            end
            if app.HSlider.Value ~= app.HField.Value
                app.HField.Value = app.HSlider.Value;
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rField.Value = app.rSlider.Value;
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Profile of Vertical Velocity');

            % Save plot as png file
            saveas(fig, 'Moeini_Romanic_profile_plot', 'png');

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for profile
            z = 0.1:0.1:1000;
            u_abl = app.ABLvelField.Value;
            u_j = app.InitialvelField.Value;
            D = app.DiameterField.Value * 1000;
            H = app.HField.Value * 1000;
            r = app.rField.Value * 1000;
            c_w = 1;
            lambda_j = 10000;
            z_m = 0.0196*H*(r/H)^0.9;
            u_db = (5.391*u_j*sqrt(c_w)*(D/H)*exp(-(15.357.*z)./(H*(r/H)^0.9)).*(z./(H*(r/H)^0.305)))/((r/H)^1.1);
            u_nl = (6.251*u_db*(r/H)^0.2*(-0.1129 + 2.6))/(sqrt(c_w)*lambda_j*(D/H));
            % (gamma_0 - epsilon_0) = 2.6 (equation 40)

            % Put y axis values into an array
            z = [0.1:0.1:1000];
            % Compute velocities and insert into array
            m = [compute_u(app, u_db, u_abl, u_nl)];

            % Arrange arrays into a table
            ar = [m; z];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2"], ["Velocity [m/s]","Height above ground [m]"]);
            
            % Write table into csv file
            writetable(t, 'Moeini_Romanic_profile_table.csv')
        end

        % Close request function: UIFigure
        function MoeiniRomanicCloseRequest(app, event)
            % Reenable Moeini Romanic button
            app.CallingApp.MoeiniRomanic2022Button.Enable = 'on';

            % Close Moeini Romanic window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MoeiniRomanicCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Profile of Vertical Velocity')
            xlabel(app.HorizontalAxes, 'Velocity [m/s]')
            ylabel(app.HorizontalAxes, 'Height above ground [m]')
            zlabel(app.HorizontalAxes, 'Z')
            app.MoeiniRomanic2022Label = uilabel(app.UIFigure);
            app.MoeiniRomanic2022Label.Text = 'Moeini & Romanic (2022)';
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.CloudbaseheightkmLabel = uilabel(app.ModelParametersPanel);
            app.CloudbaseheightkmLabel.Text = 'Cloud-base height [km]';
            app.HSlider = uislider(app.ModelParametersPanel);
            app.HSlider.Limits = [0 5];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.HSlider.Value = 2;
            app.VelocityofABLwindsmsLabel = uilabel(app.ModelParametersPanel);
            app.VelocityofABLwindsmsLabel.Text = 'Velocity of ABL winds [m/s]';
            app.ABLvelSlider = uislider(app.ModelParametersPanel);
            app.ABLvelSlider.Limits = [0 60];
            app.ABLvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLvelSlider.Value = 40;
            app.ABLvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.ABLvelField.Limits = [0 60];
            app.ABLvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLvelField.Value = 40;
            app.HField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.HField.Limits = [0 5];
            app.HField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.HField.Value = 2;
            app.DiameterofdowndraftkmLabel = uilabel(app.ModelParametersPanel);
            app.DiameterofdowndraftkmLabel.Text = 'Diameter of downdraft [km]';
            app.DiameterSlider = uislider(app.ModelParametersPanel);
            app.DiameterSlider.Limits = [0 5];
            app.DiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.DiameterSlider.Value = 2;
            app.DiameterField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.DiameterField.Limits = [0 5];
            app.DiameterField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.DiameterField.Value = 2;
            app.VelocityofdowndraftatcloudbasemsLabel = uilabel(app.ModelParametersPanel);
            app.VelocityofdowndraftatcloudbasemsLabel.Text = 'Velocity of downdraft at cloud base [m/s]';
            app.InitialvelSlider = uislider(app.ModelParametersPanel);
            app.InitialvelSlider.Limits = [0 60];
            app.InitialvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.InitialvelSlider.Value = 40;
            app.InitialvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.InitialvelField.Limits = [0 60];
            app.InitialvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.InitialvelField.Value = 40;
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.Panel = uipanel(app.UIFigure);
            app.DistancefromdowndraftcenterkmLabel = uilabel(app.Panel);
            app.DistancefromdowndraftcenterkmLabel.Text = 'Distance from downdraft center [km]';
            app.rSlider = uislider(app.Panel);
            app.rSlider.Limits = [0 3];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rSlider.Value = 1;
            app.rField = uieditfield(app.Panel, 'numeric');
            app.rField.Limits = [0 3];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MoeiniRomanic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end