classdef OBV_Chay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        ExportDataTableButton_2         matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        ModelParametersPanel            matlab.ui.container.Panel
        TotaltimeField                  matlab.ui.control.NumericEditField
        TotaltimeSlider                 matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_2  matlab.ui.control.Label
        zField                          matlab.ui.control.NumericEditField
        zSlider                         matlab.ui.control.Slider
        zmLabel                         matlab.ui.control.Label
        rField                          matlab.ui.control.NumericEditField
        rSlider                         matlab.ui.control.Slider
        DistancefromdowndraftcenterkmLabel  matlab.ui.control.Label
        rtField                         matlab.ui.control.NumericEditField
        rtSlider                        matlab.ui.control.Slider
        TimedependentradiusofmaximumoutflowvelocitykmLabel  matlab.ui.control.Label
        utransField                     matlab.ui.control.NumericEditField
        utransSlider                    matlab.ui.control.Slider
        TranslationalvelocityofstormmsLabel  matlab.ui.control.Label
        IntensificationtimeField        matlab.ui.control.NumericEditField
        IntensificationtimeSlider       matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel  matlab.ui.control.Label
        ShapingfunctionvariableField    matlab.ui.control.NumericEditField
        ShapingfunctionvariableSlider   matlab.ui.control.Slider
        ShapingfunctionvariableSliderLabel  matlab.ui.control.Label
        MaxrSlider                      matlab.ui.control.Slider
        RadiusofmaximumoutflowvelocitykmLabel  matlab.ui.control.Label
        MaxrField                       matlab.ui.control.NumericEditField
        MaxzField                       matlab.ui.control.NumericEditField
        MaxoutflowvelField              matlab.ui.control.NumericEditField
        MaxoutflowvelSlider             matlab.ui.control.Slider
        MaximumoutflowvelocitymsLabel   matlab.ui.control.Label
        MaxzSlider                      matlab.ui.control.Slider
        HeightofmaximumoutflowvelocitymLabel  matlab.ui.control.Label
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotverticalvelocityCheckBox    matlab.ui.control.CheckBox
        PlothorizontalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        ChayAlbermaniWilson2006Label    matlab.ui.control.Label
        HorizontalAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_mag = compute_umag(app, r, z, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f)
            % Horizontal downburst velocity
            u_r = i_f .* ((ur_max .* r) ./ r_t) .* ((exp(c_1 * (z/z_max)) - exp(c_2 * (z/z_max))) / (exp(c_1) - exp(c_2))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a))) + u_trans;
    
            % Vertical downburst velocity
            u_z = -2 * i_f .* ((ur_max * z_max) ./ r_max) .* (((1/c_1)*(exp(c_1 * (z/z_max)) - 1) - (1/c_2)*(exp(c_2 * (z/z_max)) - 1)) / (exp(c_1) - exp(c_2))) .* (1 - (0.5 * ((r.^2/r_max^2).^a))) .* (exp((1 - (((r.^2)/(r_max^2)).^a)) / (2*a)));
    
            % Downburst velocity magnitude
            u_mag = sqrt(u_r.^2 + u_z.^2);
        end

        function u_r = compute_ur(app, r, z, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f)
            % Horizontal downburst velocity
            u_r = i_f .* ((ur_max .* r) ./ r_t) .* ((exp(c_1 * (z/z_max)) - exp(c_2 * (z/z_max))) / (exp(c_1) - exp(c_2))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a))) + u_trans;
        end
        
        function u_z = compute_uz(app, r, z, ur_max, r_max, z_max, c_1, c_2, a, i_f)
            % Vertical downburst velocity
            u_z = -2 * i_f .* ((ur_max * z_max) ./ r_max) .* (((1/c_1)*(exp(c_1 * (z/z_max)) - 1) - (1/c_2)*(exp(c_2 * (z/z_max)) - 1)) / (exp(c_1) - exp(c_2))) .* (1 - (0.5 * ((r.^2/r_max^2).^a))) .* (exp((1 - (((r.^2)/(r_max^2)).^a)) / (2*a)));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller4)
            % Store selection menu app object
            app.CallingApp = caller4;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlothorizontalvelocityCheckBox.Value = 1;
            app.PlotverticalvelocityCheckBox.Value = 1;
            
            % Define parameters for time profile
            time = app.TotaltimeSlider.Value;
            t = 0:0.1:time;
            t_int = app.IntensificationtimeSlider.Value;
            i_f = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    i_f(i) = t(i) / t_int;
                else 
                    i_f(i) = exp((-(t(i)-t_int))/11.542);   
                end
            end
            r = app.rSlider.Value * 1000;
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_t = app.rtSlider.Value * 1000;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_umag(app, r, z, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', t, compute_ur(app, r, z, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', t, compute_uz(app, r, z, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Value changed function: IntensificationtimeSlider, 
        % MaxoutflowvelSlider, MaxrSlider, MaxzSlider, 
        % ShapingfunctionvariableSlider, TotaltimeSlider, rSlider, 
        % rtSlider, utransSlider, zSlider
        function SliderValueChanged(app, event)
            % Define parameters for time profile
            time = app.TotaltimeSlider.Value;
            t = 0:0.1:time;
            t_int = app.IntensificationtimeSlider.Value;
            i_f = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    i_f(i) = t(i) / t_int;
                else 
                    i_f(i) = exp((-(t(i)-t_int))/11.542);   
                end
            end
            r = app.rSlider.Value * 1000;
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_t = app.rtSlider.Value * 1000;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_umag(app, r, z, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', t, compute_ur(app, r, z, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', t, compute_uz(app, r, z, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelField.Value = app.MaxoutflowvelSlider.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrField.Value = app.MaxrSlider.Value;
            else
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzField.Value = app.MaxzSlider.Value;
            else
            end
            if app.utransField.Value ~= app.utransSlider.Value
                app.utransField.Value = app.utransSlider.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeField.Value = app.IntensificationtimeSlider.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeField.Value = app.TotaltimeSlider.Value;
            else
            end
            if app.ShapingfunctionvariableField.Value ~= app.ShapingfunctionvariableSlider.Value
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableSlider.Value;
            else
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zField.Value = app.zSlider.Value;
            else
            end
            if app.rtField.Value ~= app.rtSlider.Value
                app.rtField.Value = app.rtSlider.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rField.Value = app.rSlider.Value;
            end
        end

        % Value changed function: IntensificationtimeField, 
        % MaxoutflowvelField, MaxrField, MaxzField, 
        % ShapingfunctionvariableField, TotaltimeField, rField, rtField, 
        % utransField, zField
        function FieldValueChanged(app, event)
            % Define parameters for time profile
            time = app.TotaltimeField.Value;
            t = 0:0.1:time;
            t_int = app.IntensificationtimeField.Value;
            i_f = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    i_f(i) = t(i) / t_int;
                else 
                    i_f(i) = exp((-(t(i)-t_int))/11.542);   
                end
            end
            r = app.rField.Value * 1000;
            z = app.zField.Value;
            r_max = app.MaxrField.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzField.Value;
            ur_max = app.MaxoutflowvelField.Value;
            u_trans = app.utransField.Value;
            r_t = app.rtField.Value * 1000;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value;

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_umag(app, r, z, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', t, compute_ur(app, r, z, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', t, compute_uz(app, r, z, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update slider values to match fields
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelField.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrSlider.Value = app.MaxrField.Value;
            else
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzSlider.Value = app.MaxzField.Value;
            else
            end
            if app.utransField.Value ~= app.utransSlider.Value
                app.utransSlider.Value = app.utransField.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeSlider.Value = app.IntensificationtimeField.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeSlider.Value = app.TotaltimeField.Value;
            else
            end
            if app.ShapingfunctionvariableField.Value ~= app.ShapingfunctionvariableSlider.Value
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableField.Value;
            else
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            else
            end
            if app.rtField.Value ~= app.rtSlider.Value
                app.rtSlider.Value = app.rtField.Value;
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            end
        end

        % Value changed function: PlothorizontalvelocityCheckBox, 
        % PlotmagnitudeCheckBox, PlotverticalvelocityCheckBox
        function CheckBoxValueChanged(app, event)
            % Define parameters for time profile
            time = app.TotaltimeField.Value;
            t = 0:0.1:time;
            t_int = app.IntensificationtimeField.Value;
            i_f = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    i_f(i) = t(i) / t_int;
                else 
                    i_f(i) = exp((-(t(i)-t_int))/11.542);   
                end
            end
            r = app.rField.Value * 1000;
            z = app.zField.Value;
            r_max = app.MaxrField.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzField.Value;
            ur_max = app.MaxoutflowvelField.Value;
            u_trans = app.utransField.Value;
            r_t = app.rtField.Value * 1000;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value;

            % Plot time profile
            plot(app.HorizontalAxes, t, compute_umag(app, r, z, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', t, compute_ur(app, r, z, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', t, compute_uz(app, r, z, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the radial plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Time [min]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Time Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for time profile
            time = app.TotaltimeField.Value;
            t = 0.1:0.1:time;
            t_int = app.IntensificationtimeField.Value;
            i_f = zeros(1, length(t));
            for i = 1:length(t)
                if t(i) <= t_int
                    i_f(i) = t(i) / t_int;
                else 
                    i_f(i) = exp((-(t(i)-t_int))/11.542);   
                end
            end
            r = app.rField.Value * 1000;
            z = app.zField.Value;
            r_max = app.MaxrField.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzField.Value;
            ur_max = app.MaxoutflowvelField.Value;
            u_trans = app.utransField.Value;
            r_t = app.rtField.Value * 1000;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value;

            % Put x axis values into an array
            t_2 = [0.1:0.1:time];
            % Compute velocities and insert into arrays
            m = [compute_umag(app, r, z, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f)];
            h = [compute_ur(app, r, z, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f)];
            v = [compute_uz(app, r, z, ur_max, r_max, z_max, c_1, c_2, a, i_f)];

            % Arrange arrays into a table
            ar = [t_2; m; h; v];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4"], ["Time [min]","Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]"]);
            
            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function OBVChayCloseRequest(app, event)
            % Reenable OBV: Chay button
            app.CallingApp.ChayAlbermaniWilson2006Button.Enable = 'on';

            % Close OBV: Chay window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @OBVChayCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Time Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.ChayAlbermaniWilson2006Label = uilabel(app.UIFigure);
            app.ChayAlbermaniWilson2006Label.Text = 'Chay, Albermani, & Wilson (2006)';
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlothorizontalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlothorizontalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlothorizontalvelocityCheckBox.Text = 'Plot horizontal velocity';
            app.PlotverticalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotverticalvelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.PlotverticalvelocityCheckBox.Text = 'Plot vertical velocity';
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.HeightofmaximumoutflowvelocitymLabel = uilabel(app.ModelParametersPanel);
            app.HeightofmaximumoutflowvelocitymLabel.Text = 'Height of maximum outflow velocity [m]';
            app.MaxzSlider = uislider(app.ModelParametersPanel);
            app.MaxzSlider.Limits = [0 500];
            app.MaxzSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxzSlider.Value = 100;
            app.MaximumoutflowvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MaximumoutflowvelocitymsLabel.Text = 'Maximum outflow velocity [m/s]';
            app.MaxoutflowvelSlider = uislider(app.ModelParametersPanel);
            app.MaxoutflowvelSlider.Limits = [0 60];
            app.MaxoutflowvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxoutflowvelSlider.Value = 40;
            app.MaxoutflowvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxoutflowvelField.Limits = [0 60];
            app.MaxoutflowvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxoutflowvelField.Value = 40;
            app.MaxzField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxzField.Limits = [0 500];
            app.MaxzField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxzField.Value = 100;
            app.MaxrField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxrField.Limits = [0 10];
            app.MaxrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxrField.Value = 1;
            app.RadiusofmaximumoutflowvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.RadiusofmaximumoutflowvelocitykmLabel.Text = 'Radius of maximum outflow velocity [km]';
            app.MaxrSlider = uislider(app.ModelParametersPanel);
            app.MaxrSlider.Limits = [0 10];
            app.MaxrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxrSlider.Value = 1;
            app.ShapingfunctionvariableSliderLabel = uilabel(app.ModelParametersPanel);
            app.ShapingfunctionvariableSliderLabel.Text = 'Shaping function variable';
            app.ShapingfunctionvariableSlider = uislider(app.ModelParametersPanel);
            app.ShapingfunctionvariableSlider.Limits = [1 4];
            app.ShapingfunctionvariableSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ShapingfunctionvariableSlider.Value = 2;
            app.ShapingfunctionvariableField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.ShapingfunctionvariableField.Limits = [1 4];
            app.ShapingfunctionvariableField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ShapingfunctionvariableField.Value = 2;
            app.LengthofstormintensificationminSliderLabel = uilabel(app.ModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel.Text = 'Time length of storm intensification [min]';
            app.IntensificationtimeSlider = uislider(app.ModelParametersPanel);
            app.IntensificationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.IntensificationtimeSlider.Value = 10;
            app.IntensificationtimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.IntensificationtimeField.Limits = [0 100];
            app.IntensificationtimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.IntensificationtimeField.Value = 10;
            app.TranslationalvelocityofstormmsLabel = uilabel(app.ModelParametersPanel);
            app.TranslationalvelocityofstormmsLabel.Text = 'Translational velocity of storm [m/s]';
            app.utransSlider = uislider(app.ModelParametersPanel);
            app.utransSlider.Limits = [0 30];
            app.utransSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.utransSlider.Value = 10;
            app.utransField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.utransField.Limits = [0 30];
            app.utransField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.utransField.Value = 10;
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel.Text = {'Time dependent radius of maximum '; 'outflow velocity [km]'; ''};
            app.rtSlider = uislider(app.ModelParametersPanel);
            app.rtSlider.Limits = [0 10];
            app.rtSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rtSlider.Value = 1;
            app.rtField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.rtField.Limits = [0 10];
            app.rtField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rtField.Value = 1;
            app.DistancefromdowndraftcenterkmLabel = uilabel(app.ModelParametersPanel);
            app.DistancefromdowndraftcenterkmLabel.Text = 'Distance from downdraft center [km]';
            app.rSlider = uislider(app.ModelParametersPanel);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rSlider.Value = 1;
            app.rField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rField.Value = 1;
            app.zmLabel = uilabel(app.ModelParametersPanel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.ModelParametersPanel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.LengthofstormintensificationminSliderLabel_2 = uilabel(app.ModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_2.Text = 'Total time length of storm [min] ';
            app.TotaltimeSlider = uislider(app.ModelParametersPanel);
            app.TotaltimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TotaltimeSlider.Value = 45;
            app.TotaltimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TotaltimeField.Limits = [0 100];
            app.TotaltimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TotaltimeField.Value = 45;
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.engstruct.2005.07.007';
            app.Hyperlink.Text = 'Corresponding Paper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OBV_Chay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end