classdef Xhelaj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotmeridionalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotzonalvelocityCheckBox       matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        ExportDataTableButton_2         matlab.ui.control.Button
        ModelParametersPanel            matlab.ui.container.Panel
        TvelSlider                      matlab.ui.control.Slider
        TranslationalvelocitymsLabel    matlab.ui.control.Label
        TvelField                       matlab.ui.control.NumericEditField
        ABLvelSlider                    matlab.ui.control.Slider
        MeanABLwindvelocitymsLabel      matlab.ui.control.Label
        ABLvelField                     matlab.ui.control.NumericEditField
        TAngleSlider                    matlab.ui.control.Slider
        DirectionofstormtranslationdegreeLabel  matlab.ui.control.Label
        TAngleField                     matlab.ui.control.NumericEditField
        ABLAngleSlider                  matlab.ui.control.Slider
        DirectionofABLwinddegreeLabel   matlab.ui.control.Label
        ABLAngleField                   matlab.ui.control.NumericEditField
        AngleSlider                     matlab.ui.control.Slider
        AngleofdownburstobservationdegreeLabel  matlab.ui.control.Label
        AngleField                      matlab.ui.control.NumericEditField
        TotaltimeField                  matlab.ui.control.NumericEditField
        TotaltimeSlider                 matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_3  matlab.ui.control.Label
        TimeofstormSlider               matlab.ui.control.Slider
        TimeofstormField                matlab.ui.control.NumericEditField
        TimeofstormSliderLabel          matlab.ui.control.Label
        IntensificationtimeField        matlab.ui.control.NumericEditField
        IntensificationtimeSlider       matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel  matlab.ui.control.Label
        MaxrSlider                      matlab.ui.control.Slider
        RadiusofmaximumoutflowvelocitykmLabel  matlab.ui.control.Label
        MaxrField                       matlab.ui.control.NumericEditField
        MaxoutflowvelField              matlab.ui.control.NumericEditField
        MaxoutflowvelSlider             matlab.ui.control.Slider
        MaximumoutflowvelocitymsLabel   matlab.ui.control.Label
        ExportPlotButton_2              matlab.ui.control.Button
        XhelajBurlandoSolari2020Label   matlab.ui.control.Label
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_x = compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Zonal component
            u_x = (v_r .* if_r .* cosd(br)) + (v_t .* cosd(bt) .* del .* if_t) + (v_b .* cosd(bb));
        end

        function u_y = compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Meridional component
            u_y = (v_r .* if_r .* sind(br)) + (v_t .* sind(bt) .* del .* if_t) + (v_b .* sind(bb));
        end

        function u = compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)
            % Zonal component
            u_x = (v_r .* if_r .* cosd(br)) + (v_t .* cosd(bt) .* del .* if_t) + (v_b .* cosd(bb));
            % Meridional component 
            u_y = (v_r .* if_r .* sind(br)) + (v_t .* sind(bt) .* del .* if_t) + (v_b .* sind(bb));
            % Magnitude
            u = sqrt(u_x.^2 + u_y.^2);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller10)
            % Store selection menu app object
            app.CallingApp = caller10;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlotzonalvelocityCheckBox.Value = 1;
            app.PlotmeridionalvelocityCheckBox.Value = 1;

            % Define parameters for radial profile
            r_max = app.MaxrSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            br = app.AngleSlider.Value;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t = app.TimeofstormSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            for t_int = app.IntensificationtimeSlider.Value
                if t <= t_int
                    if_r = t / t_int;
                else 
                    if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            for t = app.TimeofstormSlider.Value
                if (0 <= t && t <= t_intr)
                    if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
                elseif (t_intr < t && t <= t_f)
                    if_t = 1;
                elseif (t_f < t && t <= t_fmax)
                    if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
                elseif t > t_fmax
                    if_t = 0;
                end
            end
            r = 0:0.1:10000;
            v_r = zeros(1, 100001);
            for i = 1:100001
                if r(i) <= r_max
                    v_r(i) = ur_max .* (r(i)./r_max);
                else
                    v_r(i) = ur_max .* exp(-((r(i) - r_max)./(0.5*r_max)).^2);
                end
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            del = zeros(1, 100001);
            for i = 1:100001
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', r, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', r, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Zonal Velocity', 'Meridional Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            xlim(app.HorizontalAxes, [0, r_emax]);
        end

        % Value changed function: ABLAngleSlider, ABLvelSlider, 
        % AngleSlider, IntensificationtimeSlider, MaxoutflowvelSlider, 
        % MaxrSlider, TAngleSlider, TimeofstormSlider, TotaltimeSlider, 
        % TvelSlider
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r_max = app.MaxrSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            br = app.AngleSlider.Value;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t = app.TimeofstormSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            for t_int = app.IntensificationtimeSlider.Value
                if t <= t_int
                    if_r = t / t_int;
                else 
                    if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            for t = app.TimeofstormSlider.Value
                if (0 <= t && t <= t_intr)
                    if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
                elseif (t_intr < t && t <= t_f)
                    if_t = 1;
                elseif (t_f < t && t <= t_fmax)
                    if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
                elseif t > t_fmax
                    if_t = 0;
                end
            end
            r = 0:0.1:10000;
            v_r = zeros(1, 100001);
            for i = 1:100001
                if r(i) <= r_max
                    v_r(i) = ur_max .* (r(i)./r_max);
                else
                    v_r(i) = ur_max .* exp(-((r(i) - r_max)./(0.5*r_max)).^2);
                end
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            del = zeros(1, 100001);
            for i = 1:100001
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', r, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', r, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Zonal Velocity', 'Meridional Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            xlim(app.HorizontalAxes, [0, r_emax]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlotzonalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotmeridionalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelField.Value = app.MaxoutflowvelSlider.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrField.Value = app.MaxrSlider.Value;
            else
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleField.Value = app.AngleSlider.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeField.Value = app.IntensificationtimeSlider.Value;
            else
            end
            if app.TimeofstormField.Value ~= app.TimeofstormSlider.Value
                app.TimeofstormField.Value = app.TimeofstormSlider.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeField.Value = app.TotaltimeSlider.Value;
            else
            end
            if app.TAngleField.Value ~= app.TAngleSlider.Value
                app.TAngleField.Value = app.TAngleSlider.Value;
            else
            end
            if app.ABLAngleField.Value ~= app.ABLAngleSlider.Value
                app.ABLAngleField.Value = app.ABLAngleSlider.Value;
            else
            end
            if app.TvelField.Value ~= app.TvelSlider.Value
                app.TvelField.Value = app.TvelSlider.Value;
            else
            end
            if app.ABLvelField.Value ~= app.ABLvelSlider.Value
                app.ABLvelField.Value = app.ABLvelSlider.Value;
            else
            end
        end

        % Value changed function: ABLAngleField, ABLvelField, AngleField, 
        % IntensificationtimeField, MaxoutflowvelField, MaxrField, 
        % TAngleField, TimeofstormField, TotaltimeField, TvelField
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r_max = app.MaxrField.Value * 1000;
            ur_max = app.MaxoutflowvelField.Value;
            br = app.AngleField.Value;
            v_t = app.TvelField.Value;
            bt = app.TAngleField.Value;
            v_b = app.ABLvelField.Value;
            bb = app.ABLAngleField.Value;
            t = app.TimeofstormField.Value;
            t_f = app.TotaltimeField.Value;
            for t_int = app.IntensificationtimeField.Value
                if t <= t_int
                    if_r = t / t_int;
                else 
                    if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            for t = app.TimeofstormField.Value
                if (0 <= t && t <= t_intr)
                    if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
                elseif (t_intr < t && t <= t_f)
                    if_t = 1;
                elseif (t_f < t && t <= t_fmax)
                    if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
                elseif t > t_fmax
                    if_t = 0;
                end
            end
            r = 0:0.1:10000;
            v_r = zeros(1, 100001);
            for i = 1:100001
                if r(i) <= r_max
                    v_r(i) = ur_max .* (r(i)./r_max);
                else
                    v_r(i) = ur_max .* exp(-((r(i) - r_max)./(0.5*r_max)).^2);
                end
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            del = zeros(1, 100001);
            for i = 1:100001
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'k', r, compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'r', r, compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Zonal Velocity', 'Meridional Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            xlim(app.HorizontalAxes, [0, r_emax]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlotzonalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotmeridionalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
            
            % Update field values to match sliders
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelField.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrSlider.Value = app.MaxrField.Value;
            else
            end
            if app.AngleField.Value ~= app.AngleSlider.Value
                app.AngleSlider.Value = app.AngleField.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeSlider.Value = app.IntensificationtimeField.Value;
            else
            end
            if app.TimeofstormField.Value ~= app.TimeofstormSlider.Value
                app.TimeofstormSlider.Value = app.TimeofstormField.Value;
            else
            end
            if app.TotaltimeField.Value ~= app.TotaltimeSlider.Value
                app.TotaltimeSlider.Value = app.TotaltimeField.Value;
            else
            end
            if app.TAngleField.Value ~= app.TAngleSlider.Value
                app.TAngleSlider.Value = app.TAngleField.Value;
            else
            end
            if app.ABLAngleField.Value ~= app.ABLAngleSlider.Value
                app.ABLAngleSlider.Value = app.ABLAngleField.Value;
            else
            end
            if app.TvelField.Value ~= app.TvelSlider.Value
                app.TvelSlider.Value = app.TvelField.Value;
            else
            end
            if app.ABLvelField.Value ~= app.ABLvelSlider.Value
                app.ABLvelSlider.Value = app.ABLvelField.Value;
            else
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the radial plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot as png file
            saveas(fig, 'Xhelaj_radial_profile_plot', 'png');

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            r_max = app.MaxrSlider.Value * 1000;
            ur_max = app.MaxoutflowvelSlider.Value;
            br = app.AngleSlider.Value;
            v_t = app.TvelSlider.Value;
            bt = app.TAngleSlider.Value;
            v_b = app.ABLvelSlider.Value;
            bb = app.ABLAngleSlider.Value;
            t = app.TimeofstormSlider.Value;
            t_f = app.TotaltimeSlider.Value;
            for t_int = app.IntensificationtimeSlider.Value
                if t <= t_int
                    if_r = t / t_int;
                else 
                    if_r = exp((-(t-t_int))/((t_f - t_int)/log(10)));   
                end
            end
            t_intr = 0.2 * t_int;
            t_fmax = 1.2 * t_f;
            for t = app.TimeofstormSlider.Value
                if (0 <= t && t <= t_intr)
                    if_t = 0.5 * (1 + cos((pi/t_intr)*(t-t_intr)));
                elseif (t_intr < t && t <= t_f)
                    if_t = 1;
                elseif (t_f < t && t <= t_fmax)
                    if_t = 0.5 * (1 + cos((pi/(0.2*t_f))*(t-t_f)));
                elseif t > t_fmax
                    if_t = 0;
                end
            end
            r = 0.1:0.1:10000;
            v_r = zeros(1, 100000);
            for i = 1:100000
                if r(i) <= r_max
                    v_r(i) = ur_max .* (r(i)./r_max);
                else
                    v_r(i) = ur_max .* exp(-((r(i) - r_max)./(0.5*r_max)).^2);
                end
            end
            r_e = 2 * r_max;
            r_emax = 1.2 * r_e;
            a = 0.2;
            del = zeros(1, 100000);
            for i = 1:100000
                if (0 <= r(i) && r(i) <= r_e)
                    del(i) = 1;
                elseif (r_e < r(i) && r(i) <= r_emax)
                    del(i) = 0.5 * (1 + cos((pi/(a*r_e))*(r(i)-r_e)));
                elseif r(i) >= r_emax
                    del(i) = 0;
                end
            end

            % Put x axis values into an array
            r_2 = [0.1:0.1:10000];
            % Compute velocities and insert into arrays
            m = [compute_u(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];
            zon = [compute_ux(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];
            mer = [compute_uy(app, v_r, if_r, br, v_t, bt, del, if_t, v_b, bb)];

            % Arrange arrays into a table
            ar = [r_2; m; zon; mer];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4"], ["Distance from downdraft center [m]","Magnitude [m/s]","Zonal Velocity [m/s]","Meridional Velocity [m/s]"]);
            
            % Write table into csv file
            writetable(tbl, 'Xhelaj_radial_profile_table.csv')
        end

        % Close request function: UIFigure
        function XhelajCloseRequest(app, event)
            % Reenable Xhelaj button
            app.CallingApp.XhelajBurlandoSolari2020Button.Enable = 'on';

            % Close Xhelaj window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @XhelajCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.XhelajBurlandoSolari2020Label = uilabel(app.UIFigure);
            app.XhelajBurlandoSolari2020Label.Text = 'Xhelaj, Burlando, & Solari (2020)';
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.MaximumoutflowvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MaximumoutflowvelocitymsLabel.Text = 'Maximum outflow velocity [m/s]';
            app.MaxoutflowvelSlider = uislider(app.ModelParametersPanel);
            app.MaxoutflowvelSlider.Limits = [0 60];
            app.MaxoutflowvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxoutflowvelSlider.Value = 40;
            app.MaxoutflowvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxoutflowvelField.Limits = [0 60];
            app.MaxoutflowvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxoutflowvelField.Value = 40;
            app.MaxrField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxrField.Limits = [0 3];
            app.MaxrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxrField.Value = 1;
            app.RadiusofmaximumoutflowvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.RadiusofmaximumoutflowvelocitykmLabel.Text = 'Radius of maximum outflow velocity [km]';
            app.MaxrSlider = uislider(app.ModelParametersPanel);
            app.MaxrSlider.Limits = [0 3];
            app.MaxrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxrSlider.Value = 1;
            app.LengthofstormintensificationminSliderLabel = uilabel(app.ModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel.Text = 'Time length of storm intensification [min]';
            app.IntensificationtimeSlider = uislider(app.ModelParametersPanel);
            app.IntensificationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.IntensificationtimeSlider.Value = 10;
            app.IntensificationtimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.IntensificationtimeField.Limits = [0 100];
            app.IntensificationtimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.IntensificationtimeField.Value = 10;
            app.TimeofstormSliderLabel = uilabel(app.ModelParametersPanel);
            app.TimeofstormSliderLabel.Text = 'Current time of storm [min]';
            app.TimeofstormField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TimeofstormField.Limits = [0 100];
            app.TimeofstormField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TimeofstormField.Value = 30;
            app.TimeofstormSlider = uislider(app.ModelParametersPanel);
            app.TimeofstormSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TimeofstormSlider.Value = 30;
            app.LengthofstormintensificationminSliderLabel_3 = uilabel(app.ModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_3.Text = 'Total time length of storm [min] ';
            app.TotaltimeSlider = uislider(app.ModelParametersPanel);
            app.TotaltimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TotaltimeSlider.Value = 45;
            app.TotaltimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TotaltimeField.Limits = [0 100];
            app.TotaltimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TotaltimeField.Value = 45;
            app.AngleField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.AngleField.Limits = [0 360];
            app.AngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.AngleField.Value = 90;
            app.AngleofdownburstobservationdegreeLabel = uilabel(app.ModelParametersPanel);
            app.AngleofdownburstobservationdegreeLabel.Text = 'Angle of downburst observation [degree]';
            app.AngleSlider = uislider(app.ModelParametersPanel);
            app.AngleSlider.Limits = [0 360];
            app.AngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AngleSlider.Value = 90;
            app.ABLAngleField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.ABLAngleField.Limits = [0 360];
            app.ABLAngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLAngleField.Value = 45;
            app.DirectionofABLwinddegreeLabel = uilabel(app.ModelParametersPanel);
            app.DirectionofABLwinddegreeLabel.Text = 'Direction of ABL wind [degree]';
            app.ABLAngleSlider = uislider(app.ModelParametersPanel);
            app.ABLAngleSlider.Limits = [0 360];
            app.ABLAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLAngleSlider.Value = 45;
            app.TAngleField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TAngleField.Limits = [0 360];
            app.TAngleField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TAngleField.Value = 45;
            app.DirectionofstormtranslationdegreeLabel = uilabel(app.ModelParametersPanel);
            app.DirectionofstormtranslationdegreeLabel.Text = 'Direction of storm translation [degree]';
            app.TAngleSlider = uislider(app.ModelParametersPanel);
            app.TAngleSlider.Limits = [0 360];
            app.TAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TAngleSlider.Value = 45;
            app.ABLvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.ABLvelField.Limits = [0 30];
            app.ABLvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ABLvelField.Value = 10;
            app.MeanABLwindvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MeanABLwindvelocitymsLabel.Text = 'Mean ABL wind velocity [m/s]';
            app.ABLvelSlider = uislider(app.ModelParametersPanel);
            app.ABLvelSlider.Limits = [0 30];
            app.ABLvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ABLvelSlider.Value = 10;
            app.TvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TvelField.Limits = [0 30];
            app.TvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TvelField.Value = 10;
            app.TranslationalvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.TranslationalvelocitymsLabel.Text = 'Translational velocity [m/s]';
            app.TvelSlider = uislider(app.ModelParametersPanel);
            app.TvelSlider.Limits = [0 30];
            app.TvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TvelSlider.Value = 10;
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlotzonalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotzonalvelocityCheckBox.Text = 'Plot zonal velocity';
            app.PlotmeridionalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmeridionalvelocityCheckBox.Text = 'Plot meridional velocity';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xhelaj(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end